classdef BYOBArmGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptArmGUIUIFigure  matlab.ui.Figure
        PickUpButton          matlab.ui.control.Button
        Panel                 matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        Set3Button            matlab.ui.control.Button
        saved3t3              matlab.ui.control.Label
        saved3t2              matlab.ui.control.Label
        saved3t1              matlab.ui.control.Label
        Save3Button           matlab.ui.control.Button
        saved2t1              matlab.ui.control.Label
        saved2t3              matlab.ui.control.Label
        saved2t2              matlab.ui.control.Label
        Set2Button            matlab.ui.control.Button
        Save2Button           matlab.ui.control.Button
        saved1t3              matlab.ui.control.Label
        saved1t2              matlab.ui.control.Label
        saved1t1              matlab.ui.control.Label
        Set1Button            matlab.ui.control.Button
        Save1Button           matlab.ui.control.Button
        HomeButton            matlab.ui.control.Button
        GoButton              matlab.ui.control.Button
        J3ValueEditField      matlab.ui.control.NumericEditField
        J2ValueEditField      matlab.ui.control.NumericEditField
        J1ValueEditField      matlab.ui.control.NumericEditField
        Joint3Slider          matlab.ui.control.Slider
        Joint3SliderLabel     matlab.ui.control.Label
        Joint2Slider          matlab.ui.control.Slider
        Joint2SliderLabel     matlab.ui.control.Label
        Joint1Slider          matlab.ui.control.Slider
        Joint1SliderLabel     matlab.ui.control.Label
        axes_arm              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        jointAngles = [0 0 0];

        savedAngles1 = [0 0 0];
        savedAngles2 = [0 0 0];
        savedAngles3 = [0 0 0];
    end
    
    methods (Access = private)
        
        function updateArm(app)
            % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(0, 48, -90, app.jointAngles(1));
            A2 = create_A_matrix(28, 0, 90, app.jointAngles(2));
            A3 = create_A_matrix(0, 30, 0, app.jointAngles(3));
            
            
            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1*A2;
            T0_3 = A1*A2*A3;
            
            % TODO: Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            
            % TODO: Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Begin: Code that can go into your GUI's StartupFcn.
        clc
        
        % Prepare the axes_arm properties
        app.axes_arm.View = [90 -50];
        app.axes_arm.Visible = 'off';
        
        % Once you are in Matlab App Designer you can try these axes_arm properties too.
        % These properties keep the Axes from automatically resizing (optional)
        app.axes_arm.XLim = [-60 60];
        app.axes_arm.YLim = [-50 50];
        app.axes_arm.ZLim = [-20 100];
        
        % Create vertices for all the patches
        makeStlLink("BYOBL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
        % Save references to the vertices of each patch, make points 4x1 not 3x1.
        app.link1Patch = makeStlLink("BYOBL1.stl", app.axes_arm, [112/255 198/255 210/255]);
        app.link1Vertices = get(app.link1Patch, 'Vertices')';
        app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));
        
        app.link2Patch = makeStlLink("BYOBL2.stl", app.axes_arm, [198/255 172/255 20/255]);
        app.link2Vertices = get(app.link2Patch, 'Vertices')';
        app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));
        
        app.link3Patch = makeStlLink("BYOBL3.stl", app.axes_arm, [181/255 24/255 35/255]);
        app.link3Vertices = get(app.link3Patch, 'Vertices')';
        app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));
        % End: Code that can go into your GUI's opening function.

        app.updateArm()
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            app.J1ValueEditField.Value = app.jointAngles(1);
            app.updateArm()
            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.jointAngles(1) = app.J1ValueEditField.Value;
            app.Joint1Slider.Value = app.J1ValueEditField.Value;
            app.jointAngles(2) = app.J2ValueEditField.Value;
            app.Joint2Slider.Value = app.J2ValueEditField.Value;
            app.jointAngles(3) = app.J3ValueEditField.Value;
            app.Joint3Slider.Value = app.J3ValueEditField.Value;
            app.updateArm()
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.jointAngles(1) = 0;
            app.Joint1Slider.Value = 0;
            app.J1ValueEditField.Value = 0;
            app.jointAngles(2) = 0;
            app.Joint2Slider.Value = 0;
            app.J2ValueEditField.Value = 0;
            app.jointAngles(3) = 0;
            app.Joint3Slider.Value = 0;
            app.J3ValueEditField.Value = 0;
            app.updateArm();
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.updateArm()
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            app.J3ValueEditField.Value = app.jointAngles(3);
            app.updateArm()
        end

        % Button pushed function: Save1Button
        function Save1ButtonPushed(app, event)
            app.saved1t1.Text = num2str(round(app.J1ValueEditField.Value));
            app.saved1t2.Text = num2str(round(app.J2ValueEditField.Value));
            app.saved1t3.Text = num2str(round(app.J3ValueEditField.Value));
        end

        % Button pushed function: Save2Button
        function Save2ButtonPushed(app, event)
            app.saved2t1.Text = num2str(round(app.J1ValueEditField.Value));
            app.saved2t2.Text = num2str(round(app.J2ValueEditField.Value));
            app.saved2t3.Text = num2str(round(app.J3ValueEditField.Value));
        end

        % Button pushed function: Save3Button
        function Save3ButtonPushed(app, event)
            app.saved3t1.Text = num2str(round(app.J1ValueEditField.Value));
            app.saved3t2.Text = num2str(round(app.J2ValueEditField.Value));
            app.saved3t3.Text = num2str(round(app.J3ValueEditField.Value));
        end

        % Button pushed function: Set1Button
        function Set1ButtonPushed(app, event)
            app.jointAngles(1) = str2num(app.saved1t1.Text);
            app.Joint1Slider.Value = app.jointAngles(1);
            app.J1ValueEditField.Value = app.jointAngles(1);

            app.jointAngles(2) = str2num(app.saved1t2.Text);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.J2ValueEditField.Value = app.jointAngles(2);

            app.jointAngles(3) = str2num(app.saved1t3.Text);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.J3ValueEditField.Value = app.jointAngles(3);

            app.updateArm();
        end

        % Button pushed function: Set2Button
        function Set2ButtonPushed(app, event)
            app.jointAngles(1) = str2num(app.saved2t1.Text);
            app.Joint1Slider.Value = app.jointAngles(1);
            app.J1ValueEditField.Value = app.jointAngles(1);

            app.jointAngles(2) = str2num(app.saved2t2.Text);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.J2ValueEditField.Value = app.jointAngles(2);

            app.jointAngles(3) = str2num(app.saved2t3.Text);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.J3ValueEditField.Value = app.jointAngles(3);

            app.updateArm();
        end

        % Button pushed function: Set3Button
        function Set3ButtonPushed(app, event)
            app.jointAngles(1) = str2num(app.saved3t1.Text);
            app.Joint1Slider.Value = app.jointAngles(1);
            app.J1ValueEditField.Value = app.jointAngles(1);

            app.jointAngles(2) = str2num(app.saved3t2.Text);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.J2ValueEditField.Value = app.jointAngles(2);

            app.jointAngles(3) = str2num(app.saved3t3.Text);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.J3ValueEditField.Value = app.jointAngles(3);

            app.updateArm();
        end

        % Button pushed function: PickUpButton
        function PickUpButtonPushed(app, event)
            app.jointAngles(1) = 0;
            app.jointAngles(2) = 0;
            app.jointAngles(3) = 0;
            pause(0.5);
            for theta = 0:2:90
                app.jointAngles(1) = theta;
                app.Joint1Slider.Value = app.jointAngles(1);
                app.J1ValueEditField.Value = app.jointAngles(1);
                app.updateArm();
                pause(0.02);
            end
            for theta = 0:2:180
                app.jointAngles(2) = -theta;
                app.Joint2Slider.Value = app.jointAngles(2);
                app.J2ValueEditField.Value = app.jointAngles(2);
                app.updateArm();
                pause(0.02);
            end
            for theta = -90:2:90
                app.jointAngles(3) = theta;
                app.Joint3Slider.Value = app.jointAngles(3);
                app.J3ValueEditField.Value = app.jointAngles(3);
                app.updateArm();
                pause(0.02);
            end
            for theta = 180:-2:0
                app.jointAngles(2) = -theta;
                app.Joint2Slider.Value = app.jointAngles(2);
                app.J2ValueEditField.Value = app.jointAngles(2);
                app.updateArm();
                pause(0.02);
            end
            for theta = 90:-2:-90
                app.jointAngles(1) = theta;
                app.Joint1Slider.Value = app.jointAngles(1);
                app.J1ValueEditField.Value = app.jointAngles(1);
                app.updateArm();
                pause(0.02);
            end
            for theta = 0:2:180
                app.jointAngles(2) = -theta;
                app.Joint2Slider.Value = app.jointAngles(2);
                app.J2ValueEditField.Value = app.jointAngles(2);
                app.updateArm();
                pause(0.02);
            end
            for theta = -90:2:90
                app.jointAngles(3) = theta;
                app.Joint3Slider.Value = app.jointAngles(3);
                app.J3ValueEditField.Value = app.jointAngles(3);
                app.updateArm();
                pause(0.02);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptArmGUIUIFigure = uifigure('Visible', 'off');
            app.AdeeptArmGUIUIFigure.Name = 'Adeept Arm GUI';
            app.axes_arm = uiaxes(app.AdeeptArmGUIUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.Joint1SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint2Slider.Limits = [-180 180];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.J1ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.J2ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.J3ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.GoButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.HomeButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.Panel = uipanel(app.AdeeptArmGUIUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.Save1Button = uibutton(app.GridLayout, 'push');
            app.Save1Button.ButtonPushedFcn = createCallbackFcn(app, @Save1ButtonPushed, true);
            app.Save1Button.Text = 'Save 1';
            app.Set1Button = uibutton(app.GridLayout, 'push');
            app.Set1Button.ButtonPushedFcn = createCallbackFcn(app, @Set1ButtonPushed, true);
            app.Set1Button.Text = 'Set 1';
            app.saved1t1 = uilabel(app.GridLayout);
            app.saved1t1.Text = '0';
            app.saved1t2 = uilabel(app.GridLayout);
            app.saved1t2.Text = '0';
            app.saved1t3 = uilabel(app.GridLayout);
            app.saved1t3.Text = '0';
            app.Save2Button = uibutton(app.GridLayout, 'push');
            app.Save2Button.ButtonPushedFcn = createCallbackFcn(app, @Save2ButtonPushed, true);
            app.Save2Button.Text = 'Save 2';
            app.Set2Button = uibutton(app.GridLayout, 'push');
            app.Set2Button.ButtonPushedFcn = createCallbackFcn(app, @Set2ButtonPushed, true);
            app.Set2Button.Text = 'Set 2';
            app.saved2t2 = uilabel(app.GridLayout);
            app.saved2t2.Text = '0';
            app.saved2t3 = uilabel(app.GridLayout);
            app.saved2t3.Text = '0';
            app.saved2t1 = uilabel(app.GridLayout);
            app.saved2t1.Text = '0';
            app.Save3Button = uibutton(app.GridLayout, 'push');
            app.Save3Button.ButtonPushedFcn = createCallbackFcn(app, @Save3ButtonPushed, true);
            app.Save3Button.Text = 'Save 3';
            app.saved3t1 = uilabel(app.GridLayout);
            app.saved3t1.Text = '0';
            app.saved3t2 = uilabel(app.GridLayout);
            app.saved3t2.Text = '0';
            app.saved3t3 = uilabel(app.GridLayout);
            app.saved3t3.Text = '0';
            app.Set3Button = uibutton(app.GridLayout, 'push');
            app.Set3Button.ButtonPushedFcn = createCallbackFcn(app, @Set3ButtonPushed, true);
            app.Set3Button.Text = 'Set 3';
            app.PickUpButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.PickUpButton.ButtonPushedFcn = createCallbackFcn(app, @PickUpButtonPushed, true);
            app.PickUpButton.Text = 'Pick Up';
            % Show the figure after all components are created
            app.AdeeptArmGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BYOBArmGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptArmGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptArmGUIUIFigure)
        end
    end
end