classdef matlabGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Exam1UIFigure             matlab.ui.Figure
        PeriodmsEditField         matlab.ui.control.NumericEditField
        PeriodmsEditFieldLabel    matlab.ui.control.Label
        NumFlashesEditField       matlab.ui.control.NumericEditField
        NumFlashesEditFieldLabel  matlab.ui.control.Label
        FlashButton               matlab.ui.control.Button
        LEDOffButton              matlab.ui.control.Button
        LEDOnButton               matlab.ui.control.Button
        ServerResponseLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        blink
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.blink = BlinkerRemote();
        end

        % Button pushed function: LEDOnButton
        function LEDOnButtonPushed(app, event)
            response = app.blink.LEDOn();
            app.ServerResponseLabel.Text = response;
        end

        % Button pushed function: LEDOffButton
        function LEDOffButtonPushed(app, event)
            response = app.blink.LEDOff();
            app.ServerResponseLabel.Text = response;
        end

        % Button pushed function: FlashButton
        function FlashButtonPushed(app, event)
            response = app.blink.Flash(app.NumFlashesEditField.Value, app.PeriodmsEditField.Value);
            app.ServerResponseLabel.Text = response;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Exam1UIFigure = uifigure('Visible', 'off');
            app.Exam1UIFigure.Name = 'Exam 1';
            app.ServerResponseLabel = uilabel(app.Exam1UIFigure);
            app.ServerResponseLabel.Text = 'Server Response';
            app.LEDOnButton = uibutton(app.Exam1UIFigure, 'push');
            app.LEDOnButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOnButtonPushed, true);
            app.LEDOnButton.Text = 'LED On';
            app.LEDOffButton = uibutton(app.Exam1UIFigure, 'push');
            app.LEDOffButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOffButtonPushed, true);
            app.LEDOffButton.Text = 'LED Off';
            app.FlashButton = uibutton(app.Exam1UIFigure, 'push');
            app.FlashButton.ButtonPushedFcn = createCallbackFcn(app, @FlashButtonPushed, true);
            app.FlashButton.Text = 'Flash';
            app.NumFlashesEditFieldLabel = uilabel(app.Exam1UIFigure);
            app.NumFlashesEditFieldLabel.Text = 'Num Flashes';
            app.NumFlashesEditField = uieditfield(app.Exam1UIFigure, 'numeric');
            app.PeriodmsEditFieldLabel = uilabel(app.Exam1UIFigure);
            app.PeriodmsEditFieldLabel.Text = 'Period (ms)';
            app.PeriodmsEditField = uieditfield(app.Exam1UIFigure, 'numeric');
            % Show the figure after all components are created
            app.Exam1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlabGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Exam1UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Exam1UIFigure)
        end
    end
end