classdef Exam3MQTT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Exam3MQTTGUIUIFigure  matlab.ui.Figure
        Distance0cmLabel      matlab.ui.control.Label
        BothForwardButton     matlab.ui.control.Button
        BothReverseButton     matlab.ui.control.Button
        RightForwardButton    matlab.ui.control.Button
        RightReverseButton    matlab.ui.control.Button
        LeftReverseButton     matlab.ui.control.Button
        LeftForwardButton     matlab.ui.control.Button
        RightSlider           matlab.ui.control.Slider
        RightSliderLabel      matlab.ui.control.Label
        LeftSlider            matlab.ui.control.Slider
        LeftSliderLabel       matlab.ui.control.Label
        TimeSlider            matlab.ui.control.Slider
        TimeSliderLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
        left_speed
        right_speed
    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
            fprintf("Recieved: %s\n", message)
            messageStruct = jsondecode(message);
            messageType = messageStruct.type
            
            if strcmp(message, "ultra")
                distance = messageStruct.payload;
                app.Distance0cmLabel.Text = distance;
            end
        end

        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            if nargin > 2
                messageStruct.payload = messagePayload;
            end
            messageJson = jsonencode(messageStruct);

            write(app.mqClient, "me435/rumzissc/to_pi", messageJson)
            %app.mqClient.write("chat", messageJson);
            fprintf("Sent: %s\n", messageJson)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/rumzissc/#", "Callback", @app.myCallback);
        end

        % Button pushed function: LeftForwardButton
        function LeftForwardButtonPushed(app, event)
            app.sendMessage("left",[1 app.TimeSlider.Value app.LeftSlider.Value])
        end

        % Button pushed function: LeftReverseButton
        function LeftReverseButtonPushed(app, event)
            app.sendMessage("left",[0 app.TimeSlider.Value app.LeftSlider.Value])
        end

        % Button pushed function: RightForwardButton
        function RightForwardButtonPushed(app, event)
            app.sendMessage("right",[1 app.TimeSlider.Value app.RightSlider.Value])
        end

        % Button pushed function: RightReverseButton
        function RightReverseButtonPushed(app, event)
            app.sendMessage("right",[0 app.TimeSlider.Value app.RightSlider.Value])
        end

        % Button pushed function: BothForwardButton
        function BothForwardButtonPushed(app, event)
            app.sendMessage("both",[1 app.TimeSlider.Value app.LeftSlider.Value app.RightSlider.Value])
        end

        % Button pushed function: BothReverseButton
        function BothReverseButtonPushed(app, event)
            app.sendMessage("both",[0 app.TimeSlider.Value app.LeftSlider.Value app.RightSlider.Value])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Exam3MQTTGUIUIFigure = uifigure('Visible', 'off');
            app.Exam3MQTTGUIUIFigure.Name = 'Exam 3 MQTT GUI';
            app.TimeSliderLabel = uilabel(app.Exam3MQTTGUIUIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.Exam3MQTTGUIUIFigure);
            app.TimeSlider.Limits = [0.5 4];
            app.TimeSlider.Value = 1;
            app.LeftSliderLabel = uilabel(app.Exam3MQTTGUIUIFigure);
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSlider = uislider(app.Exam3MQTTGUIUIFigure);
            app.LeftSlider.Value = 40;
            app.RightSliderLabel = uilabel(app.Exam3MQTTGUIUIFigure);
            app.RightSliderLabel.Text = 'Right';
            app.RightSlider = uislider(app.Exam3MQTTGUIUIFigure);
            app.RightSlider.Value = 40;
            app.LeftForwardButton = uibutton(app.Exam3MQTTGUIUIFigure, 'push');
            app.LeftForwardButton.ButtonPushedFcn = createCallbackFcn(app, @LeftForwardButtonPushed, true);
            app.LeftForwardButton.Text = 'Left Forward';
            app.LeftReverseButton = uibutton(app.Exam3MQTTGUIUIFigure, 'push');
            app.LeftReverseButton.ButtonPushedFcn = createCallbackFcn(app, @LeftReverseButtonPushed, true);
            app.LeftReverseButton.Text = 'Left Reverse';
            app.RightReverseButton = uibutton(app.Exam3MQTTGUIUIFigure, 'push');
            app.RightReverseButton.ButtonPushedFcn = createCallbackFcn(app, @RightReverseButtonPushed, true);
            app.RightReverseButton.Text = 'Right Reverse';
            app.RightForwardButton = uibutton(app.Exam3MQTTGUIUIFigure, 'push');
            app.RightForwardButton.ButtonPushedFcn = createCallbackFcn(app, @RightForwardButtonPushed, true);
            app.RightForwardButton.Text = 'Right Forward';
            app.BothReverseButton = uibutton(app.Exam3MQTTGUIUIFigure, 'push');
            app.BothReverseButton.ButtonPushedFcn = createCallbackFcn(app, @BothReverseButtonPushed, true);
            app.BothReverseButton.Text = 'Both Reverse';
            app.BothForwardButton = uibutton(app.Exam3MQTTGUIUIFigure, 'push');
            app.BothForwardButton.ButtonPushedFcn = createCallbackFcn(app, @BothForwardButtonPushed, true);
            app.BothForwardButton.Text = 'Both Forward';
            app.Distance0cmLabel = uilabel(app.Exam3MQTTGUIUIFigure);
            app.Distance0cmLabel.Text = 'Distance = 0 cm';
            % Show the figure after all components are created
            app.Exam3MQTTGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exam3MQTT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Exam3MQTTGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Exam3MQTTGUIUIFigure)
        end
    end
end